classdef TPMS_Designer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        ImportMenu                      matlab.ui.container.Menu
        SurfaceMeshstlMenu              matlab.ui.container.Menu
        ExportMenu                      matlab.ui.container.Menu
        SurfaceMeshstlMenu_2            matlab.ui.container.Menu
        VoxelMeshinpMenu                matlab.ui.container.Menu
        MetricHistorycsvMenu            matlab.ui.container.Menu
        VisualOptionsMenu               matlab.ui.container.Menu
        CAxisTypeMenu                   matlab.ui.container.Menu
        linearMenu                      matlab.ui.container.Menu
        logMenu                         matlab.ui.container.Menu
        CloseAllFiguresMenu             matlab.ui.container.Menu
        HistogramMenu                   matlab.ui.container.Menu
        CalculationOptionsMenu          matlab.ui.container.Menu
        mechanicalMenu                  matlab.ui.container.Menu
        CurvatureMethodMenu             matlab.ui.container.Menu
        noneMenu                        matlab.ui.container.Menu
        trimesh2Menu                    matlab.ui.container.Menu
        meyer2003Menu                   matlab.ui.container.Menu
        implicitMenu                    matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLeft                        matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        UnitCellTab                     matlab.ui.container.Tab
        GridScrollable                  matlab.ui.container.GridLayout
        SaveDesignPanel                 matlab.ui.container.Panel
        GridSize_4                      matlab.ui.container.GridLayout
        SaveButton                      matlab.ui.control.Button
        NameEditField                   matlab.ui.control.EditField
        NameEditFieldLabel              matlab.ui.control.Label
        CellSizeLxLyLzinmmPanel         matlab.ui.container.Panel
        GridSize                        matlab.ui.container.GridLayout
        lx                              matlab.ui.control.NumericEditField
        ChangeAllLabel                  matlab.ui.control.Label
        Lslider                         matlab.ui.control.Slider
        lz                              matlab.ui.control.NumericEditField
        ly                              matlab.ui.control.NumericEditField
        ResoutionPanel                  matlab.ui.container.Panel
        GridSize_3                      matlab.ui.container.GridLayout
        resolutionSlider                matlab.ui.control.Slider
        ResolutionseednodesLabel        matlab.ui.control.Label
        resolution                      matlab.ui.control.NumericEditField
        GeneralEquationPanel            matlab.ui.container.Panel
        GridGeneral                     matlab.ui.container.GridLayout
        EquationText                    matlab.ui.control.TextArea
        TypeDropDownLabel               matlab.ui.control.Label
        TypeDropDown                    matlab.ui.control.DropDown
        EquationDropDownLabel           matlab.ui.control.Label
        EquationDropDown                matlab.ui.control.DropDown
        TargetVolumeFractionLabel       matlab.ui.control.Label
        volumeSlider                    matlab.ui.control.Slider
        vol                             matlab.ui.control.NumericEditField
        v2                              matlab.ui.control.NumericEditField
        v2EditFieldLabel                matlab.ui.control.Label
        v1                              matlab.ui.control.NumericEditField
        Isovaluesv1EditFieldLabel       matlab.ui.control.Label
        HistoryTab                      matlab.ui.container.Tab
        GridLayout15                    matlab.ui.container.GridLayout
        UITable                         matlab.ui.control.Table
        ApplytostructureTab             matlab.ui.container.Tab
        GridScrollable_2                matlab.ui.container.GridLayout
        RotationPitchRollYawindegPanel  matlab.ui.container.Panel
        GridRotation                    matlab.ui.container.GridLayout
        yaw                             matlab.ui.control.NumericEditField
        roll                            matlab.ui.control.NumericEditField
        pitch                           matlab.ui.control.NumericEditField
        NumberofCellsnxnynzPanel        matlab.ui.container.Panel
        GridNCells                      matlab.ui.container.GridLayout
        ChangeAllLabel_2                matlab.ui.control.Label
        nslider                         matlab.ui.control.Slider
        nz                              matlab.ui.control.NumericEditField
        ny                              matlab.ui.control.NumericEditField
        nx                              matlab.ui.control.NumericEditField
        PhaseShiftBxByBzPanel           matlab.ui.container.Panel
        GridPhase                       matlab.ui.container.GridLayout
        bz                              matlab.ui.control.NumericEditField
        by                              matlab.ui.control.NumericEditField
        bx                              matlab.ui.control.NumericEditField
        TPMSDesignerGUIv30Label         matlab.ui.control.Label
        GridLayout13                    matlab.ui.container.GridLayout
        ResetButton                     matlab.ui.control.Button
        ProcessDataButton               matlab.ui.control.Button
        AutoUpdateSwitch                matlab.ui.control.Switch
        AutoUpdateSwitchLabel           matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        GridRight                       matlab.ui.container.GridLayout
        GridLayout14                    matlab.ui.container.GridLayout
        FaceProperty2Label              matlab.ui.control.Label
        P2                              matlab.ui.control.DropDown
        P1                              matlab.ui.control.DropDown
        FacePropertyLabel               matlab.ui.control.Label
        type                            matlab.ui.control.DropDown
        typeText                        matlab.ui.control.Label
        GridLayout12                    matlab.ui.container.GridLayout
        histogramN                      matlab.ui.control.Spinner
        sliderUI                        matlab.ui.control.Slider
        polemapN                        matlab.ui.control.Spinner
        polemapNLabel                   matlab.ui.control.Label
        sliderLabel                     matlab.ui.control.Label
        statusText                      matlab.ui.control.Label
        ExportfigureButton              matlab.ui.control.Button
        ax                              matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        myTPMS UnitCell; %Single unit cell
        cellhistory; %Array/List of unit cells
        cellID = 1; %ID of current cell (starts at 1)
        stlmesh; %Imported Mesh
        opts; %General options
        savedView = 3; %Store 3d view data 
    end
    
    methods (Access = private)
        function updateFigure(app)
            %Update all Cell Figures using h = plot(UnitCell,plottype,property1,property2,opts,ax)
            cla(app.ax); 
            opt = app.opts;

            switch opt.figureType
                case 'metrics scatterplot'
                    x = app.cellhistory.(app.P1.Value);
                    y = app.cellhistory.(app.P2.Value);
                    scatter(app.ax,x,y,'red',"filled"); grid(app.ax,"on");
                    xlabel(app.ax,app.P1.Value);ylabel(app.ax,app.P2.Value)
                case 'field viewer'
                    pID = convertStringsToChars(extractBefore(app.P1.Value+" ", " "));
                    F = app.myTPMS.F;

                    if isfield(F.property,pID)
                        cData1 = F.property.(pID);
                    else
                        cData1 = zeros(size(F.property.X));
                    end

                    % Sample Points
                    c = colorbar(app.ax); 
                    patch(app.ax,'Faces',app.myTPMS.FV.faces,'Vertices',app.myTPMS.FV.vertices,'FaceVertexCData',[0 0 0.25],'FaceAlpha',0.2,'LineStyle','none'); hold(app.ax,'on');
                    [xq, yq, zq] = meshgrid(F.xq,F.yq,app.sliderUI.Value/100*F.upper(3));
                    xyslice = interp3(F.property.X,F.property.Y,F.property.Z,cData1,xq,yq,zq);
                    surf(app.ax,xq,yq,zq,xyslice,'LineStyle',opt.meshDisp);
                    c.Label.String = 'Field Value'; colormap(app.ax,"jet");
                case 'polemap'
                    opt.n = app.polemapN.Value;
                    app.myTPMS.plot(opt.figureType,app.P1.Value,app.P2.Value,opt,app.ax);
                otherwise
                    opt.n = app.histogramN.Value;
                    app.myTPMS.plot(opt.figureType,app.P1.Value,app.P2.Value,opt,app.ax);
            end
            set(app.ax,'ColorScale',app.opts.colorscale)
            drawnow();
        end
        
        function runCurrent(app)
            tic %Start the timer
            app.ProcessDataButton.BackgroundColor = 'Yellow';
            app.ProcessDataButton.Text = 'Updating Please Wait...';
            drawnow();

            % Fundamental Properties/Parameters 
            equation = string(app.EquationDropDown.Value);
            tpmstype = string(app.TypeDropDown.Value);

            % Logic for selecting VF/IsoValue

            if strcmp(equation,'Custom')
                equation = app.EquationText.Value{1};
            end

            res = app.resolutionSlider.Value;
            cellSize = [app.lx.Value app.ly.Value app.lz.Value];
            ncells = [app.nx.Value app.ny.Value app.nz.Value];
            Rxyz = [app.pitch.Value app.roll.Value app.yaw.Value];
            offset = [app.bx.Value app.by.Value app.bz.Value];
            app.myTPMS = UnitCell(tpmstype,equation,app.vol.Value,app.v2.Value,res,cellSize);
            
            % Deal with Object/custom importing
            if contains(equation,".stl")
                    % Apply transformations now
                    app.myTPMS.FV = app.stlmesh;
                    app.myTPMS.F = app.myTPMS.FV.getField(1); % 1mm Voxel Size
                    app.myTPMS.res = app.myTPMS.F.res;
                    app.myTPMS.cellSize = app.myTPMS.F.upper-app.myTPMS.F.lower;
            end

            computeMesh = 1;
            app.myTPMS = app.myTPMS.compute(app.opts.curvature,app.mechanicalMenu.Checked,computeMesh);
            
            % Save the metric information
            if ~contains(equation,".stl")
                temp = [table(app.cellID,'VariableNames',{'ID'}) struct2table(app.myTPMS.export,'AsArray',true)];
                app.cellID = app.cellID+1;
                if isempty(app.cellhistory)
                    app.cellhistory = temp;
                else
                    app.cellhistory = vertcat(app.cellhistory,temp);
                end
            end

            if strcmp(app.myTPMS.type,'lattice')
                app.EquationText.Value = 'data/lattices/BCC.txt';
            else
                app.EquationText.Value = char(app.myTPMS.u);
            end

            app.v1.Value = app.myTPMS.v1;
            app.v2.Value = app.myTPMS.v2;
            updateFigure(app);
            
            txt = "Last operation successful after: "+string(toc)+"seconds";

            app.ProcessDataButton.BackgroundColor = 'Green';
            app.ProcessDataButton.Text = 'Update';
            app.statusText.Text = txt;
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Define default options
            app.ax = app.ax;
            axis(app.ax,"equal","vis3d","tight");
            app.opts.curvature = 'trimesh2';
            app.opts.figureType = 'surfaceMesh';
            app.opts.meshDisp = '-';
            app.opts.colorscale = 'linear';
            app.opts.currentProperty2 = app.P2.Value;
            app.opts.currentProperty = app.P1.Value;
            app.opts.currentMetric2 = "volume";
            app.opts.currentMetric1 = "ID";
            app.FaceProperty2Label.Text = 'Quiver Plot';
            runCurrent(app);
            app.P1.Items = [fieldnames(app.myTPMS.FV.Vproperty); fieldnames(app.myTPMS.FV.Fproperty)];
            app.P2.Items = ['none'; fieldnames(app.myTPMS.FV.Qproperty)];
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {844, 844};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {362, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Menu selected function: SurfaceMeshstlMenu_2
        function exportSTL(app, event)
            [file,path] = uiputfile('*.stl');
            if ~isempty(app.myTPMS.FV.facesC)
                F = [app.myTPMS.FV.faces; app.myTPMS.FV.facesC+length(app.myTPMS.FV.vertices)];
                V = [app.myTPMS.FV.vertices; app.myTPMS.FV.verticesC];
            elseif ~isempty(app.myTPMS.FV.faces)
                F = app.myTPMS.FV.faces;
                V = app.myTPMS.FV.vertices;
            else
                return;
            end
            TR = triangulation(F,V);
            stlwrite(TR,string(fullfile(path,file)));
        end

        % Menu selected function: VoxelMeshinpMenu
        function exportINP(app, event)
            [file,path] = uiputfile('*.inp');
            app.myTPMS.F.exportINP(fullfile(path,file));
        end

        % Button pushed function: ExportfigureButton
        function exportFig(app, event)
            %Copy axes to new figure and save
            figure('Name','TPMS Designer'); % New figure
            tempax = gca; % New Figure Axis Handle
            % Copy children.
            copyobj(app.ax.Children, tempax);
            % Transfer other properties.
            uiAxParams = get(app.ax);
            uiAxParamNames = fieldnames(uiAxParams);
            % Get list of editable params in new axis
            editableParams = fieldnames(set(app.ax));
            % Remove the UIAxes params that aren't editable in the new axes (add others you don't want)
            badFields = uiAxParamNames(~ismember(uiAxParamNames, editableParams));
            badFields = [badFields; 'Parent'; 'Children'; 'XAxis'; 'YAxis'; 'ZAxis'; 'Title'; 'Position';...
                'Clipping'; 'Layout';'InnerPosition';'OuterPosition'];
            uiAxGoodParams = rmfield(uiAxParams,badFields);
            
            % Set editable params on new axes
            set(tempax, uiAxGoodParams)
            c= colorbar(); title(app.type.Value);
            c.Label.String = "Colorbar Label";
        end

        % Menu selected function: MetricHistorycsvMenu
        function exportMetrics(app, event)
            [file,path] = uiputfile('*.csv');
            writetable(app.cellhistory,fullfile(path,file))
        end

        % Menu selected function: SurfaceMeshstlMenu
        function importSTL(app, event)
            [file,path] = uigetfile('*.stl');
            app.stlmesh = surfaceMesh('stl',string(fullfile(path,file))); % Create the surfaceMesh object from the .stl file
            app.EquationText.Value = file;
            app.EquationDropDown.Items = [app.EquationDropDown.Items(1:length(app.EquationDropDown.Items)) {file}];
            app.EquationDropDown.Value = file;
            runCurrent(app);
        end

        % Callback function: P1, P2, histogramN, implicitMenu, linearMenu, 
        % ...and 6 other components
        function changeOptions(app, event)

            if event.Source == app.P1
                    app.opts.currentProperty = app.P1.Value;
            elseif event.Source == app.P2 % Change Property 2
                    app.opts.currentProperty2 = app.P2.Value; 
            elseif event.Source == app.mechanicalMenu
                    app.mechanicalMenu.Checked = ~app.mechanicalMenu.Checked;
            elseif event.Source == app.linearMenu
                    app.linearMenu.Checked = 'on';
                    app.logMenu.Checked = 'off';
                    app.opts.colorscale = 'linear';
            elseif event.Source == app.logMenu
                    app.linearMenu.Checked = 'off';
                    app.logMenu.Checked = 'on';
                    app.opts.colorscale = 'log';
            elseif event.Source==app.noneMenu||event.Source==app.trimesh2Menu||...
                event.Source==app.meyer2003Menu||event.Source==app.implicitMenu % Change Curvature Case
                    app.noneMenu.Checked = 'off';
                    app.trimesh2Menu.Checked = 'off';
                    app.meyer2003Menu.Checked = 'off';
                    app.implicitMenu.Checked = 'off';
                    event.Source.Checked = 'on';
                    app.opts.curvature = event.Source.Text;
            end
            
            updateFigure(app);
        end

        % Value changed function: type
        function changeVisulisation(app, event)
            try
                % Clean up UI and save views ready for new figure type
            switch app.opts.figureType
                case "surfaceMesh"
                    app.savedView = get(app.ax,"view"); % Save view
                    app.P2.Visible = false; 
                    app.FaceProperty2Label.Text = '';
                case "polemap"
                    app.savedView = get(app.ax,"view"); % Save view
                    app.polemapN.Visible = false;
                    app.polemapNLabel.Visible = false;
                    app.P1.Visible = true;
                case "field viewer"
                    app.savedView = get(app.ax,"view"); % Save view
                    app.sliderUI.Visible = false; 
                    app.sliderLabel.Text = '';
                case "voxel"
                    app.savedView = get(app.ax,"view"); % Save view
                case "tensor"
                    app.savedView = get(app.ax,"view"); % Save view
                    app.P1.Visible = true;
                case "histogram"
                    app.histogramN.Visible = false;
                    app.polemapNLabel.Text = '';
                case "histogram2"
                    app.histogramN.Visible = false;
                    app.polemapNLabel.Text = '';
                    app.FaceProperty2Label.Text = ''; 
                    app.P2.Visible = false;
                case "metrics"
                    app.P2.Visible = false;
            end
            
            app.opts.figureType = event.Source.Value;
            cla(app.ax,'reset'); % Hard Clear Figures and viewport options
           
            switch app.opts.figureType
                case "surfaceMesh"
                    view(app.ax,app.savedView); axis(app.ax,"equal","vis3d","tight");
                    app.FacePropertyLabel.Text = "Property";
                    app.P1.Items = [fieldnames(app.myTPMS.FV.Vproperty); fieldnames(app.myTPMS.FV.Fproperty)];
                    app.P2.Items = ['none'; fieldnames(app.myTPMS.FV.Qproperty)]; app.FaceProperty2Label.Text = 'Property (Quiver plot)';
                case "polemap"
                    view(app.ax,app.savedView); axis(app.ax,"equal","vis3d","tight");
                    app.FacePropertyLabel.Text = ''; app.P1.Visible = false;
                    app.polemapN.Visible = true;
                case "tensor"
                    view(app.ax,app.savedView); axis(app.ax,"equal","vis3d","tight");
                    app.FacePropertyLabel.Text = ''; app.P1.Visible = false;
                case "histogram"
                    app.FacePropertyLabel.Text = "Property";
                    app.histogramN.Visible = true; app.polemapNLabel.Text = 'Number of Bins';
                    app.P1.Items = [fieldnames(app.myTPMS.FV.Fproperty); fieldnames(app.myTPMS.FV.Vproperty); fieldnames(app.myTPMS.F.property)];
                case "histogram2"
                    app.FacePropertyLabel.Text = "Face Property";
                    app.FaceProperty2Label.Text = "Face Property 2"; app.P2.Visible = true;
                    app.histogramN.Visible = true; app.polemapNLabel.Text = 'Number of Bins';
                    app.P1.Items = [fieldnames(app.myTPMS.FV.Vproperty); fieldnames(app.myTPMS.FV.Fproperty)]; app.P1.Value = app.P1.Items(1);
                    app.P2.Items = [fieldnames(app.myTPMS.FV.Vproperty); fieldnames(app.myTPMS.FV.Fproperty)]; app.P2.Value = app.P2.Items(2);
                case "metrics scatterplot"
                    app.FacePropertyLabel.Text = "Cell Properties";
                    app.P2.Visible = true;
                    app.P1.Items = app.cellhistory.Properties.VariableNames; app.P1.Value = app.P1.Items(1);
                    app.P2.Items = app.cellhistory.Properties.VariableNames; app.P2.Value = app.P2.Items(2);
                case "field viewer"
                    view(app.ax,app.savedView); axis(app.ax,"equal","vis3d","tight");
                    app.FacePropertyLabel.Text = "Field Name"; app.P1.Visible = true; app.sliderUI.Visible = true; 
                    app.sliderLabel.Visible = true;
                    app.P1.Items = fieldnames(app.myTPMS.F.property);
                case "voxel"
                    view(app.ax,app.savedView); axis(app.ax,"equal","vis3d","tight");
                    app.FacePropertyLabel.Text = "Field Name";
                    app.P1.Items = fieldnames(app.myTPMS.F.property);
                otherwise
                    view(app.ax,app.savedView); axis(app.ax,"equal","vis3d","tight");
                    app.P1.Items = fieldnames(app.myTPMS.F.property); app.P1.Value = app.P1.Items(1);
            end
            updateFigure(app);
            catch
                app.statusText.Text = 'Failed to update - please try again';
            end
        end

        % Callback function: ProcessDataButton, UIFigure
        function updateButton(app, event)
            % Deal with two input options
            runCurrent(app);
        end

        % Value changing function: sliderUI
        function slicesliderUIChanging(app, event)
            znew = event.Value;
            F = app.myTPMS.F;
            [xq, yq, zq] = meshgrid(F.xq,F.yq,znew/100*F.upper(3));
            pID = convertStringsToChars(extractBefore(app.P1.Value+" ", " "));
            if isfield(F.property,pID)
                cData = F.property.(pID);
            else
                cData = F.(pID);
            end
            app.ax.Children(1).CData = interp3(F.property.X,F.property.Y,F.property.Z,cData,xq,yq,zq);
            app.ax.Children(1).ZData = zq;
        end

        % Button pushed function: ResetButton
        function resetButton(app, event)
            app.lx.Value = 10; app.ly.Value = 10; app.lz.Value = 10;
            app.Lslider.Value = 10;
            app.bx.Value = 0; app.by.Value = 0; app.bz.Value = 0;
            app.nx.Value = 1; app.ny.Value = 1; app.nz.Value = 1;
            app.nslider.Value = 1;
            app.pitch.Value = 0;app.roll.Value = 0; app.yaw.Value = 0;
            app.resolutionSlider.Value = 25; app.resolution.Value = 25;
            app.TypeDropDown.Value = 'network';
            app.EquationDropDown.Value = 'Gyroid'; 
            app.volumeSlider.Value = 0.25; app.vol.Value = 0.25;
            app.cmap.Value = 'linear'; app.histogramN.Value = 100;
            app.polemapN.Value = 3;
            app.cellhistory = []; app.cellID = 1;
            runCurrent(app);
        end

        % Callback function: EquationDropDown, EquationText, Lslider, 
        % ...and 20 other components
        function changeCell(app, event)
            switch event.Source
                case app.resolution
                    app.resolutionSlider.Value = event.Value;
                case app.vol
                    app.volumeSlider.Value = event.Value;
                case app.resolutionSlider
                    app.resolution.Value = event.Value;
                case app.volumeSlider
                    app.vol.Value = event.Value;
                case app.Lslider
                    app.lx.Value = event.Value;
                    app.ly.Value = event.Value;
                    app.lz.Value = event.Value;
                case app.nslider
                    app.nx.Value = event.Value;
                    app.ny.Value = event.Value;
                    app.nz.Value = event.Value;
                case app.TypeDropDown
                    if app.TypeDropDown.Value=="lattice"
                        app.EquationDropDown.Items = {'BCC', 'FCC'};
                    else
                        app.EquationDropDown.Items = {'Gyroid', 'Diamond', 'Primitive', 'IWP', 'Neovius', 'FRD', 'Sinusoidal', 'Sphere', 'P-normCube', 'Taurus', 'Custom'};
                    end

                    switch app.TypeDropDown.Value
                        case "double"
                            app.volumeSlider.Visible = 0;
                            app.TargetVolumeFractionLabel.Visible = 0;
                            app.v1.BackgroundColor = [1 1 1];
                            app.vol.Visible = 0;
                            app.v1.Editable = 1;
                            app.v2.Visible = 1;
                            app.v2EditFieldLabel.Visible = 1;
                            app.EquationDropDown.Items = {'Gyroid', 'Diamond', 'Primitive', 'IWP', 'Neovius', 'FRD', 'Sinusoidal', 'Sphere', 'P-normCube', 'Taurus', 'Custom'};
                        case "single"
                            app.volumeSlider.Visible = 0;
                            app.v1.BackgroundColor = [1 1 1];
                            app.TargetVolumeFractionLabel.Visible = 0;
                            app.vol.Visible = 0;
                            app.v1.Editable = 1;
                            app.v2.Visible = 0;
                            app.v2EditFieldLabel.Visible = 0;
                            app.EquationDropDown.Items = {'Gyroid', 'Diamond', 'Primitive', 'IWP', 'Neovius', 'FRD', 'Sinusoidal', 'Sphere', 'P-normCube', 'Taurus', 'Custom'};
                        case "lattice"
                        otherwise
                            app.volumeSlider.Visible = 1;
                            app.TargetVolumeFractionLabel.Visible = 1;
                            app.vol.Visible = 1;
                            app.v1.BackgroundColor = [0.9 0.9 0.9];
                            app.v1.Editable = 0;
                            app.v2.Visible = 0;
                            app.v2EditFieldLabel.Visible = 0;
                            app.EquationDropDown.Items = {'Gyroid', 'Diamond', 'Primitive', 'IWP', 'Neovius', 'FRD', 'Sinusoidal', 'Sphere', 'P-normCube', 'Taurus', 'Custom'};
                    end
                case app.EquationText
                    app.EquationDropDown.Value = "Custom";
            end
            
            if app.AutoUpdateSwitch.Value
                runCurrent(app);
            end
        end

        % Menu selected function: CloseAllFiguresMenu
        function CloseAllFiguresButtonPushed(app, event)
            close all;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @updateButton, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.ImportMenu = uimenu(app.Menu);
            app.ImportMenu.Text = 'Import';
            app.SurfaceMeshstlMenu = uimenu(app.ImportMenu);
            app.SurfaceMeshstlMenu.MenuSelectedFcn = createCallbackFcn(app, @importSTL, true);
            app.SurfaceMeshstlMenu.Text = 'Surface Mesh (.stl)';
            app.ExportMenu = uimenu(app.Menu);
            app.ExportMenu.Text = 'Export';
            app.SurfaceMeshstlMenu_2 = uimenu(app.ExportMenu);
            app.SurfaceMeshstlMenu_2.MenuSelectedFcn = createCallbackFcn(app, @exportSTL, true);
            app.SurfaceMeshstlMenu_2.Text = 'Surface Mesh (.stl)';
            app.VoxelMeshinpMenu = uimenu(app.ExportMenu);
            app.VoxelMeshinpMenu.MenuSelectedFcn = createCallbackFcn(app, @exportINP, true);
            app.VoxelMeshinpMenu.Text = 'Voxel Mesh (.inp)';
            app.MetricHistorycsvMenu = uimenu(app.ExportMenu);
            app.MetricHistorycsvMenu.MenuSelectedFcn = createCallbackFcn(app, @exportMetrics, true);
            app.MetricHistorycsvMenu.Text = 'Metric History (.csv)';
            app.VisualOptionsMenu = uimenu(app.Menu);
            app.VisualOptionsMenu.Text = 'Visual Options';
            app.CAxisTypeMenu = uimenu(app.VisualOptionsMenu);
            app.CAxisTypeMenu.Text = 'CAxis Type';
            app.linearMenu = uimenu(app.CAxisTypeMenu);
            app.linearMenu.MenuSelectedFcn = createCallbackFcn(app, @changeOptions, true);
            app.linearMenu.Checked = 'on';
            app.linearMenu.Text = 'linear';
            app.logMenu = uimenu(app.CAxisTypeMenu);
            app.logMenu.MenuSelectedFcn = createCallbackFcn(app, @changeOptions, true);
            app.logMenu.Text = 'log';
            app.CloseAllFiguresMenu = uimenu(app.VisualOptionsMenu);
            app.CloseAllFiguresMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseAllFiguresButtonPushed, true);
            app.CloseAllFiguresMenu.Text = 'Close All Figures';
            app.HistogramMenu = uimenu(app.VisualOptionsMenu);
            app.HistogramMenu.Text = 'Histogram';
            app.CalculationOptionsMenu = uimenu(app.Menu);
            app.CalculationOptionsMenu.Text = 'Calculation Options';
            app.mechanicalMenu = uimenu(app.CalculationOptionsMenu);
            app.mechanicalMenu.MenuSelectedFcn = createCallbackFcn(app, @changeOptions, true);
            app.mechanicalMenu.Text = 'Mechanical Homogenisation';
            app.CurvatureMethodMenu = uimenu(app.CalculationOptionsMenu);
            app.CurvatureMethodMenu.Separator = 'on';
            app.CurvatureMethodMenu.Text = 'Curvature Method';
            app.noneMenu = uimenu(app.CurvatureMethodMenu);
            app.noneMenu.MenuSelectedFcn = createCallbackFcn(app, @changeOptions, true);
            app.noneMenu.Tooltip = {'Do not calculate curvature (or derived) properties'};
            app.noneMenu.Text = 'none';
            app.trimesh2Menu = uimenu(app.CurvatureMethodMenu);
            app.trimesh2Menu.MenuSelectedFcn = createCallbackFcn(app, @changeOptions, true);
            app.trimesh2Menu.Tooltip = {'Use the ''patch'' method for calculating curvature'};
            app.trimesh2Menu.Checked = 'on';
            app.trimesh2Menu.Text = 'trimesh2';
            app.meyer2003Menu = uimenu(app.CurvatureMethodMenu);
            app.meyer2003Menu.MenuSelectedFcn = createCallbackFcn(app, @changeOptions, true);
            app.meyer2003Menu.Tooltip = {'Use the ''trimesh'' curvature method'};
            app.meyer2003Menu.Text = 'meyer2003';
            app.implicitMenu = uimenu(app.CurvatureMethodMenu);
            app.implicitMenu.MenuSelectedFcn = createCallbackFcn(app, @changeOptions, true);
            app.implicitMenu.Tooltip = {'Use the "implicit" curvature method'};
            app.implicitMenu.Text = 'implicit';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLeft = uigridlayout(app.LeftPanel);
            app.GridLayout13 = uigridlayout(app.GridLeft);
            app.AutoUpdateSwitchLabel = uilabel(app.GridLayout13);
            app.AutoUpdateSwitchLabel.Text = 'Auto-Update';
            app.AutoUpdateSwitch = uiswitch(app.GridLayout13, 'slider');
            app.AutoUpdateSwitch.ItemsData = [0 1];
            app.AutoUpdateSwitch.Value = 1;
            app.ProcessDataButton = uibutton(app.GridLayout13, 'push');
            app.ProcessDataButton.ButtonPushedFcn = createCallbackFcn(app, @updateButton, true);
            app.ProcessDataButton.Text = 'Update';
            app.ResetButton = uibutton(app.GridLayout13, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButton, true);
            app.ResetButton.Text = 'Reset';
            app.TPMSDesignerGUIv30Label = uilabel(app.GridLeft);
            app.TPMSDesignerGUIv30Label.Text = 'TPMS Designer GUI - v3.0';
            app.TabGroup = uitabgroup(app.GridLeft);
            app.UnitCellTab = uitab(app.TabGroup);
            app.UnitCellTab.Title = 'Unit Cell';
            app.GridScrollable = uigridlayout(app.UnitCellTab);
            app.GeneralEquationPanel = uipanel(app.GridScrollable);
            app.GeneralEquationPanel.Title = 'General / Equation';
            app.GridGeneral = uigridlayout(app.GeneralEquationPanel);
            app.Isovaluesv1EditFieldLabel = uilabel(app.GridGeneral);
            app.Isovaluesv1EditFieldLabel.Text = 'Isovalue(s):   v1';
            app.v1 = uieditfield(app.GridGeneral, 'numeric');
            app.v1.ValueDisplayFormat = '%11.2g';
            app.v1.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.v1.Editable = 'off';
            app.v1.Tooltip = {'Actual Isovalue Used'};
            app.v2EditFieldLabel = uilabel(app.GridGeneral);
            app.v2EditFieldLabel.Visible = 'off';
            app.v2EditFieldLabel.Text = 'v2';
            app.v2 = uieditfield(app.GridGeneral, 'numeric');
            app.v2.ValueDisplayFormat = '%11.2g';
            app.v2.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.v2.Visible = 'off';
            app.v2.Tooltip = {'Actual Secondary Isovalue used'};
            app.vol = uieditfield(app.GridGeneral, 'numeric');
            app.vol.Limits = [0 1];
            app.vol.ValueDisplayFormat = '%11.2g';
            app.vol.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.vol.Value = 0.5;
            app.volumeSlider = uislider(app.GridGeneral);
            app.volumeSlider.Limits = [0 1];
            app.volumeSlider.ValueChangingFcn = createCallbackFcn(app, @changeCell, true);
            app.volumeSlider.Value = 0.25;
            app.TargetVolumeFractionLabel = uilabel(app.GridGeneral);
            app.TargetVolumeFractionLabel.Text = {'Target Volume '; 'Fraction'};
            app.EquationDropDown = uidropdown(app.GridGeneral);
            app.EquationDropDown.Items = {'Gyroid', 'Diamond', 'Primitive', 'IWP', 'Neovius', 'FRD', 'Sinusoidal', 'Sphere', 'P-normCube', 'Taurus', 'Custom'};
            app.EquationDropDown.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.EquationDropDown.Tooltip = {'Select the equation you wish to generate.The most common TPMS (Gyroid, Diamond, Primitive) have been included along with some sample implicit objects (Sinusoidal, Sphere, PnormCube, Taurus).'; ''; '"Custom" to modify or write a new equation.  '; 'All entries after Custom will be imported structures (.stl or lattices)'};
            app.EquationDropDown.Value = 'Gyroid';
            app.EquationDropDownLabel = uilabel(app.GridGeneral);
            app.EquationDropDownLabel.Tooltip = {''};
            app.EquationDropDownLabel.Text = 'Equation';
            app.TypeDropDown = uidropdown(app.GridGeneral);
            app.TypeDropDown.Items = {'network', 'surface', 'single', 'double', 'lattice'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.TypeDropDown.Tooltip = {'Select the type of TPMS you wish to generate.'; ''; 'network: Produces a skeletal structure using a target volume fraction as the input the isovalue v1 is defined to achieve close to the target volume fraction. (Finds volume with: U ≤ v1).'; ''; 'surface: Produces a surface with midplane on the zero isosurface at a target volume fraction. This calculates the isovalues +v1 and -v1.'; '(Finds volume with: -v1 ≤ U ≤ v1)'; ''; 'single: Similar to network, but procudes a result directly from the isovalue'; ''; 'double: General form which directly finds the volume bounded by two isovalues v1 and v2.'; '(Finds volume with: v1 ≤ U ≤ v2)'};
            app.TypeDropDown.Value = 'network';
            app.TypeDropDownLabel = uilabel(app.GridGeneral);
            app.TypeDropDownLabel.Tooltip = {''};
            app.TypeDropDownLabel.Text = 'Type';
            app.EquationText = uitextarea(app.GridGeneral);
            app.EquationText.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.EquationText.Tooltip = {'Enter your custom function here. Must be a valid matlab function with the format for example: @(x,y,z) x.*y+z-3*x '};
            app.EquationText.Value = {'Equation'};
            app.ResoutionPanel = uipanel(app.GridScrollable);
            app.ResoutionPanel.Title = 'Resoution';
            app.GridSize_3 = uigridlayout(app.ResoutionPanel);
            app.resolution = uieditfield(app.GridSize_3, 'numeric');
            app.resolution.Limits = [2 128];
            app.resolution.RoundFractionalValues = 'on';
            app.resolution.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.resolution.Tooltip = {'Number of nodes per unit cell (in x,y,z directions).'; ''; 'Increasing this will increase the mesh quality at the cost of computational time'};
            app.resolution.Value = 25;
            app.ResolutionseednodesLabel = uilabel(app.GridSize_3);
            app.ResolutionseednodesLabel.Text = 'Resolution (seed nodes)';
            app.resolutionSlider = uislider(app.GridSize_3);
            app.resolutionSlider.Limits = [2 128];
            app.resolutionSlider.ValueChangingFcn = createCallbackFcn(app, @changeCell, true);
            app.resolutionSlider.Tooltip = {''};
            app.resolutionSlider.Value = 20;
            app.CellSizeLxLyLzinmmPanel = uipanel(app.GridScrollable);
            app.CellSizeLxLyLzinmmPanel.Title = 'Cell Size (Lx, Ly, Lz in mm)';
            app.GridSize = uigridlayout(app.CellSizeLxLyLzinmmPanel);
            app.ly = uieditfield(app.GridSize, 'numeric');
            app.ly.LowerLimitInclusive = 'off';
            app.ly.UpperLimitInclusive = 'off';
            app.ly.Limits = [0 Inf];
            app.ly.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.ly.Value = 10;
            app.lz = uieditfield(app.GridSize, 'numeric');
            app.lz.LowerLimitInclusive = 'off';
            app.lz.UpperLimitInclusive = 'off';
            app.lz.Limits = [0 Inf];
            app.lz.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.lz.Value = 10;
            app.Lslider = uislider(app.GridSize);
            app.Lslider.Limits = [0 20];
            app.Lslider.ValueChangingFcn = createCallbackFcn(app, @changeCell, true);
            app.Lslider.Value = 10;
            app.ChangeAllLabel = uilabel(app.GridSize);
            app.ChangeAllLabel.Text = 'Change All:';
            app.lx = uieditfield(app.GridSize, 'numeric');
            app.lx.LowerLimitInclusive = 'off';
            app.lx.UpperLimitInclusive = 'off';
            app.lx.Limits = [0 Inf];
            app.lx.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.lx.Value = 10;
            app.SaveDesignPanel = uipanel(app.GridScrollable);
            app.SaveDesignPanel.Title = 'Save Design';
            app.GridSize_4 = uigridlayout(app.SaveDesignPanel);
            app.NameEditFieldLabel = uilabel(app.GridSize_4);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.GridSize_4, 'text');
            app.NameEditField.Value = 'Default';
            app.SaveButton = uibutton(app.GridSize_4, 'push');
            app.SaveButton.Text = 'Save';
            app.HistoryTab = uitab(app.TabGroup);
            app.HistoryTab.Title = 'History';
            app.GridLayout15 = uigridlayout(app.HistoryTab);
            app.UITable = uitable(app.GridLayout15);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.ApplytostructureTab = uitab(app.TabGroup);
            app.ApplytostructureTab.Title = 'Apply to structure';
            app.GridScrollable_2 = uigridlayout(app.ApplytostructureTab);
            app.PhaseShiftBxByBzPanel = uipanel(app.GridScrollable_2);
            app.PhaseShiftBxByBzPanel.Title = 'Phase Shift (Bx, By, Bz)';
            app.GridPhase = uigridlayout(app.PhaseShiftBxByBzPanel);
            app.bx = uieditfield(app.GridPhase, 'numeric');
            app.bx.Limits = [0 6.2832];
            app.bx.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.by = uieditfield(app.GridPhase, 'numeric');
            app.by.Limits = [0 6.2832];
            app.by.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.bz = uieditfield(app.GridPhase, 'numeric');
            app.bz.Limits = [0 6.2832];
            app.bz.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.NumberofCellsnxnynzPanel = uipanel(app.GridScrollable_2);
            app.NumberofCellsnxnynzPanel.Title = 'Number of Cells (nx, ny, nz)';
            app.GridNCells = uigridlayout(app.NumberofCellsnxnynzPanel);
            app.nx = uieditfield(app.GridNCells, 'numeric');
            app.nx.LowerLimitInclusive = 'off';
            app.nx.UpperLimitInclusive = 'off';
            app.nx.Limits = [0 Inf];
            app.nx.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.nx.Value = 1;
            app.ny = uieditfield(app.GridNCells, 'numeric');
            app.ny.LowerLimitInclusive = 'off';
            app.ny.UpperLimitInclusive = 'off';
            app.ny.Limits = [0 Inf];
            app.ny.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.ny.Value = 1;
            app.nz = uieditfield(app.GridNCells, 'numeric');
            app.nz.LowerLimitInclusive = 'off';
            app.nz.UpperLimitInclusive = 'off';
            app.nz.Limits = [0 Inf];
            app.nz.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.nz.Value = 1;
            app.nslider = uislider(app.GridNCells);
            app.nslider.Limits = [0 5];
            app.nslider.MajorTicks = [0 1 2 3 4 5];
            app.nslider.ValueChangingFcn = createCallbackFcn(app, @changeCell, true);
            app.nslider.Value = 2.52427184466019;
            app.ChangeAllLabel_2 = uilabel(app.GridNCells);
            app.ChangeAllLabel_2.Text = 'Change All:';
            app.RotationPitchRollYawindegPanel = uipanel(app.GridScrollable_2);
            app.RotationPitchRollYawindegPanel.Title = 'Rotation (Pitch, Roll, Yaw in deg)';
            app.GridRotation = uigridlayout(app.RotationPitchRollYawindegPanel);
            app.pitch = uieditfield(app.GridRotation, 'numeric');
            app.pitch.Limits = [0 180];
            app.pitch.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.roll = uieditfield(app.GridRotation, 'numeric');
            app.roll.Limits = [0 180];
            app.roll.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.yaw = uieditfield(app.GridRotation, 'numeric');
            app.yaw.Limits = [0 180];
            app.yaw.ValueChangedFcn = createCallbackFcn(app, @changeCell, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.GridRight = uigridlayout(app.RightPanel);
            app.ax = uiaxes(app.GridRight);
            xlabel(app.ax, 'X')
            ylabel(app.ax, 'Y')
            app.GridLayout12 = uigridlayout(app.GridRight);
            app.ExportfigureButton = uibutton(app.GridLayout12, 'push');
            app.ExportfigureButton.ButtonPushedFcn = createCallbackFcn(app, @exportFig, true);
            app.ExportfigureButton.Text = 'Export figure';
            app.statusText = uilabel(app.GridLayout12);
            app.statusText.Text = 'Initialising';
            app.sliderLabel = uilabel(app.GridLayout12);
            app.sliderLabel.Visible = 'off';
            app.sliderLabel.Text = 'Z-Height';
            app.polemapNLabel = uilabel(app.GridLayout12);
            app.polemapNLabel.Visible = 'off';
            app.polemapNLabel.Text = 'Polemap divisions';
            app.polemapN = uispinner(app.GridLayout12);
            app.polemapN.Limits = [1 5];
            app.polemapN.RoundFractionalValues = 'on';
            app.polemapN.ValueChangedFcn = createCallbackFcn(app, @changeOptions, true);
            app.polemapN.Visible = 'off';
            app.polemapN.Tooltip = {'Number of division of the icosphere used for the polemap. (More divisions will require higher resolution meshing for good results)'};
            app.polemapN.Value = 3;
            app.sliderUI = uislider(app.GridLayout12);
            app.sliderUI.MajorTicks = [0 50 100];
            app.sliderUI.MajorTickLabels = {''};
            app.sliderUI.ValueChangingFcn = createCallbackFcn(app, @slicesliderUIChanging, true);
            app.sliderUI.Visible = 'off';
            app.histogramN = uispinner(app.GridLayout12);
            app.histogramN.Step = 10;
            app.histogramN.Limits = [1 500];
            app.histogramN.RoundFractionalValues = 'on';
            app.histogramN.ValueChangedFcn = createCallbackFcn(app, @changeOptions, true);
            app.histogramN.Visible = 'off';
            app.histogramN.Tooltip = {'Number of division of the icosphere used for the polemap. (More divisions will require higher resolution meshing for good results)'};
            app.histogramN.Value = 100;
            app.GridLayout14 = uigridlayout(app.GridRight);
            app.typeText = uilabel(app.GridLayout14);
            app.typeText.Text = 'Plot type';
            app.type = uidropdown(app.GridLayout14);
            app.type.Items = {'Surface Mesh', 'Field viewer', 'voxel', 'histogram', 'histogram2', 'polemap', 'tensor', 'Metrics Scatterplot'};
            app.type.ItemsData = {'surfaceMesh', 'field viewer', 'voxel', 'histogram', 'histogram2', 'polemap', 'tensor', 'metrics scatterplot'};
            app.type.ValueChangedFcn = createCallbackFcn(app, @changeVisulisation, true);
            app.type.Tooltip = {'Visualisation types:'; ''; 'surfaceMesh - Mesh coloured by a selected property'; 'voxel - Voxelated model coloured by selected property'; 'histogram - Shows distribution of a given property'; 'histogram2 - Shows distribution of 2-property combinations'; 'polemap - Mapping of surface orientations onto a sphere'; 'field viewer - Show the value of a field at various Z-slices'; 'tensor - Show a visualisation of the cauchy stiffness tensor'; 'metric history - Shows the historically saved metric data in an xy scatterplot'};
            app.type.Value = 'surfaceMesh';
            app.FacePropertyLabel = uilabel(app.GridLayout14);
            app.FacePropertyLabel.Text = 'Property';
            app.P1 = uidropdown(app.GridLayout14);
            app.P1.Items = {'inclination'};
            app.P1.ValueChangedFcn = createCallbackFcn(app, @changeOptions, true);
            app.P1.Value = 'inclination';
            app.P2 = uidropdown(app.GridLayout14);
            app.P2.Items = {'none'};
            app.P2.ValueChangedFcn = createCallbackFcn(app, @changeOptions, true);
            app.P2.Value = 'none';
            app.FaceProperty2Label = uilabel(app.GridLayout14);
            app.FaceProperty2Label.Text = 'Property 2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TPMS_Designer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end